classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UploadButton            matlab.ui.control.Button
        EnterFIlenamehereLabel  matlab.ui.control.Label
        Inputfield              matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
        StartProcessingButton   matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        ProcessPPGButton        matlab.ui.control.Button
        TimePeriodSliderLabel   matlab.ui.control.Label
        TimePeriodSlider        matlab.ui.control.Slider
        ProcessperfusionButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        UIFigure2
        Butterworth=butterworth
        green;
        green1;
        value;
        period=20;
        ppg;
        ppg1;
        perf;
        perf1;
        flag=0;
        t;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            rawData=xlsread(app.value)           
            app.green=rawData(:,3)
            app.green1=app.green(1280:(1280+app.period*64))
            
             run("perfandppg.m")
             perfandppg=xlsread("perfandppg.csv")           
             app.ppg=perfandppg(:,2)
             app.perf=perfandppg(:,1)
             delete perfandppg.csv

         
            plot(app.UIAxes,app.green1,'black')
        end

        % Value changed function: Inputfield
        function InputfieldValueChanged(app, event)
            app.value = app.Inputfield.Value;
            
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
             
             app.ppg1=app.ppg(1280:(1280+app.period*64))
             app.perf1=app.perf(1280:(1280+app.period*64))
             csvwrite('perf1andppg1.csv',[app.perf1,app.ppg1])
                   
          
            app.flag=1             
            plot(app.UIAxes2,app.ppg1,'black')
            pause(1)
            plot(app.UIAxes3,app.perf1,'black')
            
        end

        % Value changing function: TimePeriodSlider
        function TimePeriodSliderValueChanging(app, event)
            
            app.period = event.Value;  
            app.green1=app.green(1280:(1280+app.period*64))
            plot(app.UIAxes,app.green1,'black')
        end

        % Button pushed function: ProcessPPGButton
        function ProcessPPGButtonPushed(app, event)
            if(app.flag==1)
                {app2}
            else
                msgbox('PPG not available');
            end         
            
            %app.UIFigure.Visible = 'off';
           
        end

        % Button pushed function: ProcessperfusionButton
        function ProcessperfusionButtonPushed(app, event)
            if(app.flag==1)
                {app3}
            else
                msgbox('Perfusion not available');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.EnterFIlenamehereLabel = uilabel(app.UIFigure);
            app.EnterFIlenamehereLabel.Text = 'Enter FIlename here: ';
            app.Inputfield = uieditfield(app.UIFigure, 'text');
            app.Inputfield.ValueChangedFcn = createCallbackFcn(app, @InputfieldValueChanged, true);
            app.Inputfield.Value = 'xyz.csv';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw PPG')
            xlabel(app.UIAxes, 'Number of samples(Time Period*sampling rate)')
            ylabel(app.UIAxes, 'Raw PPG')
            app.StartProcessingButton = uibutton(app.UIFigure, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start Processing';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PPG Waveform with motion artifacts(MA)')
            xlabel(app.UIAxes2, 'Number of samples(Time Period*sampling rate)')
            ylabel(app.UIAxes2, 'Processed PPG')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Perfusion')
            xlabel(app.UIAxes3, 'Number of samples(Time Period*sampling rate)')
            ylabel(app.UIAxes3, 'Perfusion')
            app.ProcessPPGButton = uibutton(app.UIFigure, 'push');
            app.ProcessPPGButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessPPGButtonPushed, true);
            app.ProcessPPGButton.Text = 'Process PPG';
            app.TimePeriodSliderLabel = uilabel(app.UIFigure);
            app.TimePeriodSliderLabel.Text = 'Time Period';
            app.TimePeriodSlider = uislider(app.UIFigure);
            app.TimePeriodSlider.Limits = [20 40];
            app.TimePeriodSlider.MajorTicks = [20 25 30 35 40];
            app.TimePeriodSlider.ValueChangingFcn = createCallbackFcn(app, @TimePeriodSliderValueChanging, true);
            app.TimePeriodSlider.Value = 20;
            app.ProcessperfusionButton = uibutton(app.UIFigure, 'push');
            app.ProcessperfusionButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessperfusionButtonPushed, true);
            app.ProcessperfusionButton.Text = 'Process perfusion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end