classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExitButton                     matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        KurtosisSpreadCleansedPPGButton  matlab.ui.control.Button
        SkewnessSpreadCleansedPPGButton  matlab.ui.control.Button
        SkewnessSpreadPPGwithMAButton  matlab.ui.control.Button
        KurtosisSpreadPPGwithMAButton  matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ppg;
        PPG_ref_data;
        training_data;
        CI_Skew;
        CI_Kur;
        X_test;
        CI_Skew_;
        CI_Kur_;
        windows;
        cleansed_data;
        out;


    end


    methods (Access = public)
        
        function display_detections(app,data,windows)
        detected_visual=zeros(length(data),1);
        if length(windows(:,1))==1
            detected_visual(windows(1)*64:windows(2)*64)=data((windows(1)*64:windows(2)*64));
        else
            for i=1:length(windows)
                detected_visual(windows(i,1)*64:windows(i,2)*64)=data((windows(i,1)*64:windows(i,2)*64));
            end
        end
        plot(app.UIAxes4,obtain_time(app,data),data,'b')
        hold(app.UIAxes4, 'on' )
        plot(app.UIAxes4,obtain_time(app,detected_visual),detected_visual,'r')

        legend(app.UIAxes4,'Clean Data','MA Affected Data')
        end
        
        function display_detections2(app,data,windows)
        detected_visual=zeros(length(data),1);
        if length(windows(:,1))==1
            detected_visual(windows(1)*64:windows(2)*64)=data((windows(1)*64:windows(2)*64));
        else
            for i=1:length(windows)
                detected_visual(windows(i,1)*64:windows(i,2)*64)=data((windows(i,1)*64:windows(i,2)*64));
            end
        end
        plot(app.UIAxes5,obtain_time(app,data),data,'b')
        hold(app.UIAxes5, 'on' )
        plot(app.UIAxes5,obtain_time(app,detected_visual),detected_visual,'r')
        legend(app.UIAxes5,'Original PPG Data', 'Replaced Data')
        N2 = length(detected_visual);
        F2 = linspace(-64/2,64/2,length(detected_visual));
        plot(app.UIAxes6,F2,abs(fftshift(fft(detected_visual))));
        xlim(app.UIAxes6,[0.05 10])
        
        end


       function time=obtain_time(app,data)
       fs=64;
       time=1:1:length(data);
       time=time/fs;
       end
       
                
        function khirki = get_windows(app,X_test,jump,CI_Kur,CI_skew)
        
        window=[];
        time_init=0;
        time_final=0;
        windows=[];
        i=1;
        caught=false;
        while i < length(X_test(:,1))
            if ((X_test(i,1)>CI_Kur(1)) ||  (X_test(i,1)<CI_Kur(2))) && ((X_test(i,2)>CI_skew(1)) ||  (X_test(i,2)<CI_skew(2)))
                time_init=round(X_test(i,3)-0.5);
                if time_init==0
                    time_init=1;
                end
                window=[];
                prev_time=time_init;
                time_final=0;
                index=i;
                
                while 1
                    count_motion=0;
                    if prev_time + jump > length(X_test(:,1))
                        break
                    end
                    while X_test(index,3) < prev_time + jump
                        if ((X_test(index,1)>CI_Kur(1)) ||  (X_test(index,1)<CI_Kur(2))) && ((X_test(index,2)>CI_skew(1)) ||  (X_test(index,2)<CI_skew(2)))
                            count_motion=count_motion+1; 
                        end
                        index=index+1;
                        if index > length(X_test(:,1))
                            index=length(X_test(:,1));
                            break;
                        end
                    end
                    if count_motion<1
                        break
                    else
                        
                    end
                    prev_time=round(X_test(index,3));              
                end
                time_init=time_init;
                time_final=prev_time;
                max=round(X_test(length(X_test(:,3)),3)-0.5);
                if time_final*64 > max*64
                    display(time_final)
                    display(max)
                    window=[time_init max];
                else
                    window=[time_init time_final];
                end
                windows=[windows;window];
                if caught==true
                    break
                end
                i=index;
            end
            i=i+1;
        end
        khirki=windows;
        end
            end
    
    methods (Access = private)
        
        function results = plot_stats(app,stat,data,koc)
        CI=stat.*ones(length(data),2);
        if koc=='Kurtosis'
            plot(data(:,3),data(:,1),'o')
            hold on
            plot(data(:,3),CI,'LineWidth',3)
            title('Kurtosis Spread')
            xlabel('time/sec')
            ylabel('magnitude')
            ylim([0 8])
            legend('Kurtosis','Upper Bound','Lower Bound')
        elseif koc=='Skewness'
            plot(data(:,3),data(:,2),'o')
            hold on
            plot(data(:,3),CI,'LineWidth',2)
            title('Skewness Spread')
            xlabel('time/sec')
            ylabel('magnitude')
            ylim([-3 3])
            legend('Skewness','Upper Bound','Lower Bound')
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            run("Final_working.mlx")
        
            perfandppg=xlsread("perf1andppg1.csv")           
            app.ppg=perfandppg(:,2)           
%             plot(app.UIAxes,app.ppg,'black')
            
            PPG_ref=xlsread("PPG_ref.csv")
            app.PPG_ref_data=PPG_ref(:,1)
%             plot(app.UIAxes2,app.PPG_ref_data)
            
            N = length(app.ppg);
            F = linspace(-64/2,64/2,length(app.ppg));
            plot(app.UIAxes3,F,abs(fftshift(fft(app.ppg))));
            xlim(app.UIAxes3,[0.05 10])
            
            app.training_data=xlsread("training_data.csv")            
            Kur=xlsread("CI_Kur.csv")
            app.CI_Kur=Kur(1,:)
            skew=xlsread("CI_skew.csv")
            app.CI_Skew=skew(1,:)
            
            app.X_test=xlsread("X_test.csv")
            app.CI_Skew_=app.CI_Skew.*ones(length(app.X_test(:,3)),2);
            app.CI_Kur_=app.CI_Kur.*ones(length(app.X_test(:,3)),2);
            
            jump=2;
            app.windows=get_windows(app,app.X_test,jump,app.CI_Kur,app.CI_Skew);
            display_detections(app,app.ppg,app.windows)
            
            
            app.cleansed_data=importdata("cleansed.csv")
            app.cleansed_data(length(app.ppg))=0
            display_detections2(app,app.cleansed_data,app.windows)
            app.out=xlsread("out.csv")


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            delete(app)

        end

        % Button pushed function: SkewnessSpreadPPGwithMAButton
        function SkewnessSpreadPPGwithMAButtonPushed(app, event)
            figure          
            plot_stats(app,app.CI_Skew,app.X_test,'Skewness')
            title('Skewness Spread of PPG with MA')
        end

        % Button pushed function: KurtosisSpreadPPGwithMAButton
        function KurtosisSpreadPPGwithMAButtonPushed(app, event)
            figure           
            plot_stats(app,app.CI_Kur,app.X_test,'Kurtosis')
            title('Kurtosis Spread of PPG with MA')
        end

        % Button pushed function: SkewnessSpreadCleansedPPGButton
        function SkewnessSpreadCleansedPPGButtonPushed(app, event)
            figure          
            plot_stats(app,app.CI_Skew_,app.out,'Skewness')
            title('Skewness Spread of Cleansed PPG')
        end

        % Button pushed function: KurtosisSpreadCleansedPPGButton
        function KurtosisSpreadCleansedPPGButtonPushed(app, event)
            figure          
            plot_stats(app,app.CI_Kur_,app.out,'Kurtosis')
            title('Kurtosis Spread of Cleansed PPG')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'FFT of PPG with MA')
            xlabel(app.UIAxes3, 'Freq(Hz)')
            ylabel(app.UIAxes3, 'Magnitude')
            app.KurtosisSpreadCleansedPPGButton = uibutton(app.UIFigure, 'push');
            app.KurtosisSpreadCleansedPPGButton.ButtonPushedFcn = createCallbackFcn(app, @KurtosisSpreadCleansedPPGButtonPushed, true);
            app.KurtosisSpreadCleansedPPGButton.Text = 'Kurtosis Spread Cleansed PPG';
            app.SkewnessSpreadCleansedPPGButton = uibutton(app.UIFigure, 'push');
            app.SkewnessSpreadCleansedPPGButton.ButtonPushedFcn = createCallbackFcn(app, @SkewnessSpreadCleansedPPGButtonPushed, true);
            app.SkewnessSpreadCleansedPPGButton.Text = 'Skewness Spread Cleansed PPG';
            app.SkewnessSpreadPPGwithMAButton = uibutton(app.UIFigure, 'push');
            app.SkewnessSpreadPPGwithMAButton.ButtonPushedFcn = createCallbackFcn(app, @SkewnessSpreadPPGwithMAButtonPushed, true);
            app.SkewnessSpreadPPGwithMAButton.Text = 'Skewness Spread PPG with MA';
            app.KurtosisSpreadPPGwithMAButton = uibutton(app.UIFigure, 'push');
            app.KurtosisSpreadPPGwithMAButton.ButtonPushedFcn = createCallbackFcn(app, @KurtosisSpreadPPGwithMAButtonPushed, true);
            app.KurtosisSpreadPPGwithMAButton.Text = 'Kurtosis Spread PPG with MA';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Detected MA in PPG')
            xlabel(app.UIAxes4, 'Period')
            ylabel(app.UIAxes4, 'PPG')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Cleansed PPG')
            xlabel(app.UIAxes5, 'Period')
            ylabel(app.UIAxes5, 'PPG')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'FFT of Cleansed PPG')
            xlabel(app.UIAxes6, 'Freq(Hz)')
            ylabel(app.UIAxes6, 'Magnitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end