classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        ExitButton           matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        BreathRateLabel      matlab.ui.control.Label
        BreathRateEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        perf;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            perfandppg=xlsread("perf1andppg1.csv")           
            app.perf=perfandppg(:,1)  
            time=(1:1:length(app.perf))
            time=time/64
            plot(app.UIAxes,time,app.perf,'black')


            Resp_Signal=bandpass(app.perf,[0.25 0.35],64)
            [pks, locs]=findpeaks(Resp_Signal,'MinPeakDistance',32) 
            x_peaks = time(locs);


            pause(3)
            plot(app.UIAxes2,time,Resp_Signal,  x_peaks,pks,'o')
            
            distance=[]
            for i=1:length(pks)-1
                  difference=locs(i+1)-locs(i)
            
                   distance=[distance difference]
            end
            dis_mean=mean(distance)
            pause(3)
            app.BreathRateEditField.Value=int2str(round((64*60)/dis_mean))


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: BreathRateEditField
        function BreathRateEditFieldValueChanged(app, event)
            value = app.BreathRateEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Perfusion')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Perfusion')
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'respiratory signal at 0.3 Hz')
            xlabel(app.UIAxes2, 'Time(sec)')
            ylabel(app.UIAxes2, 'Respiratory signal')
            app.BreathRateLabel = uilabel(app.UIFigure);
            app.BreathRateLabel.Text = 'Breath Rate   ';
            app.BreathRateEditField = uieditfield(app.UIFigure, 'text');
            app.BreathRateEditField.ValueChangedFcn = createCallbackFcn(app, @BreathRateEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end